classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FanPanel                 matlab.ui.container.Panel
        FanCtrlModeButton        matlab.ui.control.Button
        CurrentspeedLabel        matlab.ui.control.Label
        valorLabel               matlab.ui.control.Label
        FanSlider                matlab.ui.control.Slider
        BtnEnviarVelocidad       matlab.ui.control.Button
        ConnectionmanagerPanel   matlab.ui.container.Panel
        BaudeRateDropDown        matlab.ui.control.DropDown
        BaudLabel                matlab.ui.control.Label
        RefreshButton            matlab.ui.control.Button
        AvailablePortsDropDown   matlab.ui.control.DropDown
        AvailablePortsLabel      matlab.ui.control.Label
        BtnStartSerial           matlab.ui.control.Button
        LampConnStatus           matlab.ui.control.Lamp
        SavedataPanel            matlab.ui.container.Panel
        BtnThingSpeak            matlab.ui.control.Button
        BtnSQL                   matlab.ui.control.Button
        StreamPanel              matlab.ui.container.Panel
        BtnStartStream           matlab.ui.control.Button
        BtnStopStream            matlab.ui.control.Button
        Chart1Panel              matlab.ui.container.Panel
        CheckBox_4C1             matlab.ui.control.CheckBox
        VariableDropDown_4C1     matlab.ui.control.DropDown
        VariableDropDown_4Label  matlab.ui.control.Label
        ValueDropDown_4C1        matlab.ui.control.DropDown
        ValueDropDown_4Label     matlab.ui.control.Label
        CheckBox_3C1             matlab.ui.control.CheckBox
        VariableDropDown_3C1     matlab.ui.control.DropDown
        VariableDropDown_3Label  matlab.ui.control.Label
        ValueDropDown_3C1        matlab.ui.control.DropDown
        ValueDropDown_3Label     matlab.ui.control.Label
        CheckBox_2C1             matlab.ui.control.CheckBox
        VariableDropDown_2C1     matlab.ui.control.DropDown
        VariableDropDown_2Label  matlab.ui.control.Label
        ValueDropDown_2C1        matlab.ui.control.DropDown
        ValueDropDown_2Label     matlab.ui.control.Label
        ValueDropDown_1C1        matlab.ui.control.DropDown
        ValueDropDownLabel       matlab.ui.control.Label
        VariableDropDown_1C1     matlab.ui.control.DropDown
        VariableDropDownLabel    matlab.ui.control.Label
        CheckBox_1C1             matlab.ui.control.CheckBox
        Chart2Panel              matlab.ui.container.Panel
        CheckBox_1C2             matlab.ui.control.CheckBox
        CheckBox_2C2             matlab.ui.control.CheckBox
        CheckBox_3C2             matlab.ui.control.CheckBox
        CheckBox_4C2             matlab.ui.control.CheckBox
        VariableDropDown_1C2     matlab.ui.control.DropDown
        VariableDropDown_8Label  matlab.ui.control.Label
        ValueDropDown_1C2        matlab.ui.control.DropDown
        ValueDropDown_8Label     matlab.ui.control.Label
        VariableDropDown_2C2     matlab.ui.control.DropDown
        VariableDropDown_7Label  matlab.ui.control.Label
        ValueDropDown_2C2        matlab.ui.control.DropDown
        ValueDropDown_7Label     matlab.ui.control.Label
        VariableDropDown_3C2     matlab.ui.control.DropDown
        VariableDropDown_6Label  matlab.ui.control.Label
        ValueDropDown_3C2        matlab.ui.control.DropDown
        ValueDropDown_6Label     matlab.ui.control.Label
        VariableDropDown_4C2     matlab.ui.control.DropDown
        VariableDropDown_5Label  matlab.ui.control.Label
        ValueDropDown_4C2        matlab.ui.control.DropDown
        ValueDropDown_5Label     matlab.ui.control.Label
        grafico2                 matlab.ui.control.UIAxes
        grafico1                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s %arduino
        %Tin %temperatura interior 
        Data
        colum = 8;
        PlotCtrl = zeros(2,4,3) % que filas deben ser agregadas al gráfigo (checkbox)
        maxDatos = 1000;
        stream = false;
        CleanDataCheck = false;    
        Thingspeak = false;
        csv = false;
        csvfile;
        Automatico = false;
    end
    
    methods (Access = public)


        function plotLectura(app,Ctrl,D,cont,L)

            A = zeros(L,app.colum+1);
            t = ["Tin" "Hin" "Tout" "Hout" "FanSpeed" "Energy"];
            tt = string(zeros(1,8));
            i = 1;
            j = 1;
            g1 = 0;
            g2 = 0;

            % app.TinGauge.Value = D(cont,3);
            % app.EnergyGauge.Value = D(cont,8);

            R = Ctrl(:,:,1).*Ctrl(:,:,2);

            while i <= 2
                j=1;
                while  j <= 4
                    if R(i,j) > 0                          
                        if i == 1
                            g1 = g1 + 1;
                            tt(1,g1) = t(R(i,j)-2);

                            if Ctrl(i, j, 3) == 1                                
                                A(:,g1) = D(:,R(i,j)); 
                            elseif Ctrl(i, j, 3) == 2                                
                                A(1:cont,g1) = Lregression(app, 1:1:cont, D(1:cont,R(i,j)));
                            end

                        elseif i == 2
                            g2 = g2 + 1;                            
                            tt(1,g2+4) = t(R(i,j)-2);

                            if Ctrl(i, j, 3) == 1                                
                                A(:,g2+4) = D(:,R(i,j)); 
                            elseif Ctrl(i, j, 3) == 2
                                A(1:cont,g2+4) = Lregression(app, 1:1:cont, D(1:cont,R(i,j)));
                            end
                        end
                    end
                    j = j+1;
                end 
                i = i+1;
            end

            % A(1:cont,:)

            if dot(Ctrl(1,:,1),Ctrl(1,:,1)) > 0
                plot(app.grafico1, A(1:cont,1:g1));
                legend(app.grafico1,tt(1:g1));
            end

            if dot(Ctrl(2,:,1),Ctrl(2,:,1)) > 0
                plot(app.grafico2,A(1:cont,5:g2+4));
                legend(app.grafico2,tt(5:g2+4));
            end

        end


        function E = Energy(app,Row)
            %valores leídos
            Tin = str2double(Row(1));
            Hin = str2double(Row(2));
            Tout = str2double(Row(3));
            
            %consideraciones generales

            % intervalo de temperatura
            T = [0.01, 10, 20 ,30 ,40 ,50];
        
            % presión atmosferica [KPa]
            Patm = 100;
        
            % constante de gas ideal aire [KPa*m3/Kg*K]
            Rair = 0.287;
        
            % diámetro del ventilador [cm]
            d = 10;
        
            % área del ventilador [m2]
            A = pi*(d/100)^2;
        
            % velocidad de entrada del aire [m/s]
            Vin = 10;
        
            % flujo volumétríco [m3/min]
            vp = Vin*A;

            % variables cengel

            % calor específico aire [KJ/Kg°C] se considera constante para el rango de T
            Cp = 1.005;
        
            % entalpía de saturación del vapor [KJ/Kg] aprox a la entalpía
            % saturación para el rango de T
            hv = [2500.9 ,2519.2 ,2537.4, 2555.6 ,2573.5 ,2591.3];
        
            % presión de  saturación vapor [KPa] para una cierta T
            Psat = [0.6117, 1.2281, 2.3392, 4.2469, 7.3851, 12.352];

            % cálculos

            %datos de tablas
            Psat1 = interp1(T, Psat, Tin,'spline'); % presión de saturación para la temperatura de entrada
            hg1 = interp1(T, hv, Tin,'spline'); % entalpía de saturación en la entrada
            hg2 = interp1(T, hv, Tout,'spline'); % entalpía de saturación en la salida
        
            %presión de vapor 1
            Pv1 = Hin*Psat1;
        
            %presión del aire en la entrada
            Pa1 = Patm-Pv1;
        
            %volumen específico 1
            v1 = Rair*(Tin+273.15)/Pa1;
        
            %flujo másico de aire 1
            ma = vp/v1;

            %humedad absoluta 1
            w1 = 0.622*Pv1/(Patm-Pv1);
        
            %entalpía 1
            h1 = Cp*Tin+w1*hg1;
                    
            %entapía 2
            h2 = Cp*Tout+w1*hg2;
        
            %energía
            E = ma*h2-ma*h1;
        
        end
        
        function L = Lregression(app, x, y)  

            L = polyval(polyfit(x, y, 1),x);
            
        end
        
        function F = FanCtrl(app,T)
            
            if T >= 70
                F = 255;
            else
                F = 0;
            end

        end
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AvailablePortsDropDown.Items = serialportlist("available");
            app.grafico1.YLim = [0 90];
            app.grafico2.YLim = [0 90];
            app.BtnThingSpeak.Enable = "off";
            app.BtnSQL.Enable = "off";
            app.BtnStartStream.Enable = "off";
            app.BtnStopStream.Enable = "off";
            app.valorLabel.Text = "-";
            app.BtnEnviarVelocidad.Enable = "off";
            app.LampConnStatus.Color = "red";
            app.FanCtrlModeButton.Text = "manual";
            app.valorLabel.Text = "0";

            app.grafico1.YLim = [0,300];
            app.grafico2.YLim = [0,300];
            grid(app.grafico1, "on")   
            grid(app.grafico2, "on")  
            app.grafico1.YMinorGrid = "on";
            app.grafico2.YMinorGrid = "on";


            P = ["-" "Tin" "Hin" "Tout" "Hout" "FanSpeed" "Energy"];
            V = ["RowValue" "Lregresion"];

            app.VariableDropDown_1C1.Items = P;
            app.VariableDropDown_2C1.Items = P;
            app.VariableDropDown_3C1.Items = P;
            app.VariableDropDown_4C1.Items = P;

            app.VariableDropDown_1C2.Items = P;
            app.VariableDropDown_2C2.Items = P;
            app.VariableDropDown_3C2.Items = P;
            app.VariableDropDown_4C2.Items = P;

            app.ValueDropDown_1C1.Items = V;
            app.ValueDropDown_2C1.Items = V;
            app.ValueDropDown_3C1.Items = V;
            app.ValueDropDown_4C1.Items = V;

            app.ValueDropDown_1C2.Items = V;
            app.ValueDropDown_2C2.Items = V;
            app.ValueDropDown_3C2.Items = V;
            app.ValueDropDown_4C2.Items = V;
        end

        % Button pushed function: BtnStartSerial
        function BtnStartSerialButtonPushed(app, event)
            COM = app.AvailablePortsDropDown.Value;
            
            app.s = serialport(COM,9600,Timeout=5);
            app.stream = true;
            
            fprintf("arduino conextado")    

            app.BtnStartSerial.Enable = "off";
            app.BtnStartStream.Enable = "on";
            app.BtnEnviarVelocidad.Enable = "on";

            if ~isempty(app.s)
                app.LampConnStatus.Color = "green";
            end

        end

        % Button pushed function: BtnStartStream
        function BtnStartStreamButtonPushed(app, event)
            
             app.BtnStartStream.Enable = "off";
             app.BtnStopStream.Enable = "on";
             app.BtnThingSpeak.Enable = "on";
             app.BtnSQL.Enable = "on";

             app.Data = zeros(app.maxDatos,app.colum);

             cont = 1;

             persistent lastSendTime;

             while app.stream          

                 datos = readline(app.s);
                 Row = split(datos,",");
                 %length(strlength(Row))

                 if length(strlength(Row)) == 5

                     %fprintf("condición cumplida\n")
                     %row

                     Row(6) = app.Energy(Row);
                     datef = string(datetime('now','Format','d-MMM-y'));
                     dateh = string(datetime('now','Format','HH:mm:ss'));
                     app.Data(cont,:) = [datef dateh Row'];
                     T = str2double((Row(1)));
                     %[datef dateh Row'];  
                     %Row

                     app.PlotCtrl(1,:,1) = [app.CheckBox_1C1.Value 
                                               app.CheckBox_2C1.Value 
                                               app.CheckBox_3C1.Value 
                                               app.CheckBox_4C1.Value];

                     app.PlotCtrl(2,:,1) = [app.CheckBox_1C2.Value 
                                               app.CheckBox_2C2.Value 
                                               app.CheckBox_3C2.Value 
                                               app.CheckBox_4C2.Value];

                     app.PlotCtrl(1,:,2) = [app.VariableDropDown_1C1.Value 
                                              app.VariableDropDown_2C1.Value
                                              app.VariableDropDown_3C1.Value
                                              app.VariableDropDown_4C1.Value];

                     app.PlotCtrl(2,:,2) = [app.VariableDropDown_1C2.Value
                                              app.VariableDropDown_2C2.Value
                                              app.VariableDropDown_3C2.Value 
                                              app.VariableDropDown_4C2.Value];

                     app.PlotCtrl(1,:,3) = [app.ValueDropDown_1C1.Value 
                                              app.ValueDropDown_2C1.Value
                                              app.ValueDropDown_3C1.Value
                                              app.ValueDropDown_4C1.Value];

                     app.PlotCtrl(2,:,3) = [app.ValueDropDown_1C1.Value
                                              app.ValueDropDown_2C1.Value
                                              app.ValueDropDown_3C1.Value 
                                              app.ValueDropDown_4C1.Value];

                     Ctrl = app.PlotCtrl;
                     D = app.Data;
                     L = app.maxDatos;

                     app.plotLectura(Ctrl,D,cont,L);
                     
                     if app.Thingspeak
                        currentTime = datetime('now');
                        
                        % Initialize the timer if it's the first time sending data
                        if isempty(lastSendTime)
                            lastSendTime = currentTime;
                        end
                        
                        % Check if 20 seconds have passed since the last send
                        if seconds(currentTime - lastSendTime) >= 20
                            % Update lastSendTime
                            lastSendTime = currentTime;
                            
                            % Set up ThingSpeak parameters
                            WriteKey = 'JW4268ZH1X54GXAD';
                            ChannelID = 1976106;
                    
                            % Send data to ThingSpeak with a timeout of 100 seconds
                            try
                                thingSpeakWrite( ...
                                    ChannelID, ...
                                    {str2double(Row(1)), str2double(Row(2)), str2double(Row(3)), ...
                                     str2double(Row(4)), str2double(Row(5)), str2double(Row(6))}, ...
                                    'WriteKey', WriteKey, 'TimeStamp', currentTime, 'Timeout', 100);
                            catch ME
                                warning("Failed to send data to ThingSpeak: %s", ME.message);
                            end
                        end
                     end
                        
                     
                     if app.Automatico 

                         F2 = FanCtrl(app,T);

                         fanSpeed = strcat(';',string(round(F2)));
                         writeline(app.s,fanSpeed);  

                         app.valorLabel.Text = string(round(FanCtrl(app,T)));
                     end


                     if app.csv   
                         filename = app.csvfile;  
                         row = [datef dateh Row'];

                         if isfile(filename)
                             writematrix(row,filename,'WriteMode','append')
                         else                             
                             writematrix(row,filename)
                         end
                     end

                    
                     cont = cont + 1;      
                 end
                 pause(1)
             end
             
        end

        % Button pushed function: BtnStopStream
        function BtnStopStreamButtonPushed(app, event)
            app.stream = false;
            app.BtnStartSerial.Enable = "on";
            app.BtnStartStream.Enable = "on";
            app.BtnStopStream.Enable = "off";
            app.csv = false;
            app.BtnSQL.BackgroundColor = "red";
        end

        % Button pushed function: BtnEnviarVelocidad
        function BtnEnviarVelocidadButtonPushed(app, event)
            fanSpeed = strcat(';',string(round(app.FanSlider.Value)));
            writeline(app.s,fanSpeed);  
            app.valorLabel.Text = string(round(app.FanSlider.Value));
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.AvailablePortsDropDown.Items = serialportlist("all");
            app.BtnStartSerial.Enable = "on";

            if ~isempty(app.s)
                flush(app.s)
            end
            
            
        end

        % Button pushed function: BtnThingSpeak
        function BtnThingSpeakButtonPushed(app, event)
            if app.Thingspeak
                app.Thingspeak = false;
            else
                app.Thingspeak= true;
            end
        end

        % Button pushed function: BtnSQL
        function BtnSQLButtonPushed(app, event)

            app.csvfile = strcat('datosleidos','.csv');

            if app.csv 
                app.csv = false;
                app.BtnSQL.BackgroundColor = "red";
            else
                app.csv = true;
                app.BtnSQL.BackgroundColor = "green";
            end
        end

        % Button pushed function: FanCtrlModeButton
        function FanCtrlModeButtonPushed(app, event)
                        
            if app.FanCtrlModeButton.Text == "Automatic"
                app.FanCtrlModeButton.Text = "Manual";
                app.Automatico = true; 
            else
                app.FanCtrlModeButton.Text = "Automatic";
                app.Automatico = false;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafico1 = uiaxes(app.UIFigure);
            title(app.grafico1, 'chart 1')
            xlabel(app.grafico1, 'X')
            ylabel(app.grafico1, 'Y')
            zlabel(app.grafico1, 'Z')
            app.grafico2 = uiaxes(app.UIFigure);
            title(app.grafico2, 'chart 2')
            xlabel(app.grafico2, 'X')
            ylabel(app.grafico2, 'Y')
            zlabel(app.grafico2, 'Z')
            app.Chart2Panel = uipanel(app.UIFigure);
            app.Chart2Panel.Title = 'Chart 2';
            app.ValueDropDown_5Label = uilabel(app.Chart2Panel);
            app.ValueDropDown_5Label.Text = 'Value';
            app.ValueDropDown_4C2 = uidropdown(app.Chart2Panel);
            app.ValueDropDown_4C2.ItemsData = [1 2];
            app.ValueDropDown_4C2.Value = 1;
            app.VariableDropDown_5Label = uilabel(app.Chart2Panel);
            app.VariableDropDown_5Label.Text = 'Variable';
            app.VariableDropDown_4C2 = uidropdown(app.Chart2Panel);
            app.VariableDropDown_4C2.ItemsData = [1 3 4 5 6 7 8];
            app.VariableDropDown_4C2.Value = 1;
            app.ValueDropDown_6Label = uilabel(app.Chart2Panel);
            app.ValueDropDown_6Label.Text = 'Value';
            app.ValueDropDown_3C2 = uidropdown(app.Chart2Panel);
            app.ValueDropDown_3C2.ItemsData = [1 2];
            app.ValueDropDown_3C2.Value = 1;
            app.VariableDropDown_6Label = uilabel(app.Chart2Panel);
            app.VariableDropDown_6Label.Text = 'Variable';
            app.VariableDropDown_3C2 = uidropdown(app.Chart2Panel);
            app.VariableDropDown_3C2.ItemsData = [1 3 4 5 6 7 8];
            app.VariableDropDown_3C2.Value = 1;
            app.ValueDropDown_7Label = uilabel(app.Chart2Panel);
            app.ValueDropDown_7Label.Text = 'Value';
            app.ValueDropDown_2C2 = uidropdown(app.Chart2Panel);
            app.ValueDropDown_2C2.ItemsData = [1 2];
            app.ValueDropDown_2C2.Value = 1;
            app.VariableDropDown_7Label = uilabel(app.Chart2Panel);
            app.VariableDropDown_7Label.Text = 'Variable';
            app.VariableDropDown_2C2 = uidropdown(app.Chart2Panel);
            app.VariableDropDown_2C2.ItemsData = [1 3 4 5 6 7 8];
            app.VariableDropDown_2C2.Value = 1;
            app.ValueDropDown_8Label = uilabel(app.Chart2Panel);
            app.ValueDropDown_8Label.Text = 'Value';
            app.ValueDropDown_1C2 = uidropdown(app.Chart2Panel);
            app.ValueDropDown_1C2.ItemsData = [1 2];
            app.ValueDropDown_1C2.Value = 1;
            app.VariableDropDown_8Label = uilabel(app.Chart2Panel);
            app.VariableDropDown_8Label.Text = 'Variable';
            app.VariableDropDown_1C2 = uidropdown(app.Chart2Panel);
            app.VariableDropDown_1C2.ItemsData = [1 3 4 5 6 7 8];
            app.VariableDropDown_1C2.Value = 1;
            app.CheckBox_4C2 = uicheckbox(app.Chart2Panel);
            app.CheckBox_4C2.Text = '';
            app.CheckBox_3C2 = uicheckbox(app.Chart2Panel);
            app.CheckBox_3C2.Text = '';
            app.CheckBox_2C2 = uicheckbox(app.Chart2Panel);
            app.CheckBox_2C2.Text = '';
            app.CheckBox_1C2 = uicheckbox(app.Chart2Panel);
            app.CheckBox_1C2.Text = '';
            app.Chart1Panel = uipanel(app.UIFigure);
            app.Chart1Panel.Title = 'Chart 1';
            app.CheckBox_1C1 = uicheckbox(app.Chart1Panel);
            app.CheckBox_1C1.Text = '';
            app.VariableDropDownLabel = uilabel(app.Chart1Panel);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown_1C1 = uidropdown(app.Chart1Panel);
            app.VariableDropDown_1C1.ItemsData = [1 3 4 5 6 7 8];
            app.VariableDropDown_1C1.Value = 1;
            app.ValueDropDownLabel = uilabel(app.Chart1Panel);
            app.ValueDropDownLabel.Text = 'Value';
            app.ValueDropDown_1C1 = uidropdown(app.Chart1Panel);
            app.ValueDropDown_1C1.ItemsData = [1 2];
            app.ValueDropDown_1C1.Value = 1;
            app.ValueDropDown_2Label = uilabel(app.Chart1Panel);
            app.ValueDropDown_2Label.Text = 'Value';
            app.ValueDropDown_2C1 = uidropdown(app.Chart1Panel);
            app.ValueDropDown_2C1.ItemsData = [1 2];
            app.ValueDropDown_2C1.Value = 1;
            app.VariableDropDown_2Label = uilabel(app.Chart1Panel);
            app.VariableDropDown_2Label.Text = 'Variable';
            app.VariableDropDown_2C1 = uidropdown(app.Chart1Panel);
            app.VariableDropDown_2C1.ItemsData = [1 3 4 5 6 7 8];
            app.VariableDropDown_2C1.Value = 1;
            app.CheckBox_2C1 = uicheckbox(app.Chart1Panel);
            app.CheckBox_2C1.Text = '';
            app.ValueDropDown_3Label = uilabel(app.Chart1Panel);
            app.ValueDropDown_3Label.Text = 'Value';
            app.ValueDropDown_3C1 = uidropdown(app.Chart1Panel);
            app.ValueDropDown_3C1.ItemsData = [1 2];
            app.ValueDropDown_3C1.Value = 1;
            app.VariableDropDown_3Label = uilabel(app.Chart1Panel);
            app.VariableDropDown_3Label.Text = 'Variable';
            app.VariableDropDown_3C1 = uidropdown(app.Chart1Panel);
            app.VariableDropDown_3C1.ItemsData = [1 3 4 5 6 7 8];
            app.VariableDropDown_3C1.Value = 1;
            app.CheckBox_3C1 = uicheckbox(app.Chart1Panel);
            app.CheckBox_3C1.Text = '';
            app.ValueDropDown_4Label = uilabel(app.Chart1Panel);
            app.ValueDropDown_4Label.Text = 'Value';
            app.ValueDropDown_4C1 = uidropdown(app.Chart1Panel);
            app.ValueDropDown_4C1.ItemsData = [1 2];
            app.ValueDropDown_4C1.Value = 1;
            app.VariableDropDown_4Label = uilabel(app.Chart1Panel);
            app.VariableDropDown_4Label.Text = 'Variable';
            app.VariableDropDown_4C1 = uidropdown(app.Chart1Panel);
            app.VariableDropDown_4C1.ItemsData = [1 3 4 5 6 7 8];
            app.VariableDropDown_4C1.Value = 1;
            app.CheckBox_4C1 = uicheckbox(app.Chart1Panel);
            app.CheckBox_4C1.Text = '';
            app.StreamPanel = uipanel(app.UIFigure);
            app.StreamPanel.Title = 'Stream';
            app.BtnStopStream = uibutton(app.StreamPanel, 'push');
            app.BtnStopStream.ButtonPushedFcn = createCallbackFcn(app, @BtnStopStreamButtonPushed, true);
            app.BtnStopStream.Text = 'Stop';
            app.BtnStartStream = uibutton(app.StreamPanel, 'push');
            app.BtnStartStream.ButtonPushedFcn = createCallbackFcn(app, @BtnStartStreamButtonPushed, true);
            app.BtnStartStream.Text = 'Start';
            app.SavedataPanel = uipanel(app.UIFigure);
            app.SavedataPanel.Title = 'Save data';
            app.BtnSQL = uibutton(app.SavedataPanel, 'push');
            app.BtnSQL.ButtonPushedFcn = createCallbackFcn(app, @BtnSQLButtonPushed, true);
            app.BtnSQL.Text = 'CSV';
            app.BtnThingSpeak = uibutton(app.SavedataPanel, 'push');
            app.BtnThingSpeak.ButtonPushedFcn = createCallbackFcn(app, @BtnThingSpeakButtonPushed, true);
            app.BtnThingSpeak.Text = 'ThingSpeak';
            app.ConnectionmanagerPanel = uipanel(app.UIFigure);
            app.ConnectionmanagerPanel.Title = 'Connection manager';
            app.LampConnStatus = uilamp(app.ConnectionmanagerPanel);
            app.BtnStartSerial = uibutton(app.ConnectionmanagerPanel, 'push');
            app.BtnStartSerial.ButtonPushedFcn = createCallbackFcn(app, @BtnStartSerialButtonPushed, true);
            app.BtnStartSerial.Text = 'Connect';
            app.AvailablePortsLabel = uilabel(app.ConnectionmanagerPanel);
            app.AvailablePortsLabel.Text = 'Available Port(s):';
            app.AvailablePortsDropDown = uidropdown(app.ConnectionmanagerPanel);
            app.AvailablePortsDropDown.Items = {};
            app.AvailablePortsDropDown.Value = {};
            app.RefreshButton = uibutton(app.ConnectionmanagerPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.BaudLabel = uilabel(app.ConnectionmanagerPanel);
            app.BaudLabel.Text = 'Baude Rate:';
            app.BaudeRateDropDown = uidropdown(app.ConnectionmanagerPanel);
            app.BaudeRateDropDown.Items = {'9600', '19200', '115200', '128000'};
            app.BaudeRateDropDown.Value = '9600';
            app.FanPanel = uipanel(app.UIFigure);
            app.FanPanel.Title = 'Fan';
            app.BtnEnviarVelocidad = uibutton(app.FanPanel, 'push');
            app.BtnEnviarVelocidad.ButtonPushedFcn = createCallbackFcn(app, @BtnEnviarVelocidadButtonPushed, true);
            app.BtnEnviarVelocidad.Text = 'Send new';
            app.FanSlider = uislider(app.FanPanel);
            app.FanSlider.Limits = [0 255];
            app.valorLabel = uilabel(app.FanPanel);
            app.valorLabel.Text = 'valor';
            app.CurrentspeedLabel = uilabel(app.FanPanel);
            app.CurrentspeedLabel.Text = 'Current speed:';
            app.FanCtrlModeButton = uibutton(app.FanPanel, 'push');
            app.FanCtrlModeButton.ButtonPushedFcn = createCallbackFcn(app, @FanCtrlModeButtonPushed, true);
            app.FanCtrlModeButton.Text = 'Atomatic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end